classdef YouBotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StopSensorButton                matlab.ui.control.Button
        UpdateIntervalsecondsEditField  matlab.ui.control.NumericEditField
        UpdateIntervalsecondsEditFieldLabel  matlab.ui.control.Label
        ActionLabel                     matlab.ui.control.Label
        Reconnect                       matlab.ui.control.Button
        EndSimulationButton             matlab.ui.control.Button
        Directions                      matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        clientID
        sim remApi
        h
        open
        sensorHandle
        LaserTimer timer
        map
        vision
        mapInit logical = false;
        angularVel
        globalTheta
        currentTheta
        currentVelocity
        currentAngVelocity
        dt
        step
        distance_to_bot
        youBotHandle
        continueExecute
        Running logical = false;
        TimerObj timer
        LogOddsMap
        inputVelocity
        inputAngularVelocity
        inputStop logical = false;
        Robot_x
        Robot_y
        sensor1Handle = 0
        sensor2Handle = 0
    end
    
    methods (Access = private)
        
        function blnSucceed = movementSet(app,forwBackVel, leftRightVel, rotVel)
            %   Move robot forward. Just a test
            if  app.clientID < 0
                disp('Check server connection');
                blnSucceed = false;
                return;
            end

            app.sim.simxSetJointTargetVelocity(app.clientID, app.h(1),-forwBackVel-leftRightVel-rotVel, app.sim.simx_opmode_blocking);
            app.sim.simxSetJointTargetVelocity(app.clientID, app.h(2),-forwBackVel+leftRightVel-rotVel, app.sim.simx_opmode_blocking);
            app.sim.simxSetJointTargetVelocity(app.clientID, app.h(3),-forwBackVel-leftRightVel+rotVel, app.sim.simx_opmode_blocking);
            app.sim.simxSetJointTargetVelocity(app.clientID, app.h(4),-forwBackVel+leftRightVel+rotVel, app.sim.simx_opmode_blocking);
            blnSucceed = true;           
            app.continueExecute = false;
        end

        function blnSucceed = setJointMovement(app,jointIndex, targetTheta)
            %   Move robot forward. Just a test
            if  app.clientID < 0
                disp('Check server connection');
                blnSucceed = false;
                return;
            end
            if jointIndex > 11 || jointIndex < 1 
                disp('Joint Index out of bound');
                blnSucceed = false;
                return;
            end

            app.sim.simxSetJointTargetPosition(app.clientID, app.h(jointIndex),targetTheta, app.sim.simx_opmode_blocking);
            blnSucceed = true;           
        end        
        
        function stepSimulation(app)
            if app.Running
                PlotState(app);
                UpdateState(app);
                NextAction(app);
                app.sim.simxSynchronousTrigger(app.clientID);
            end            
        end
      

        function stopSimulation(app)
            stop(app.LaserTimer);
            delete(app.LaserTimer);
            stop(app.TimerObj);
            delete(app.TimerObj);   
            app.sim.simxFinish(app.clientID);
            app.sim.simxStopSimulation(app.clientID,app.sim.simx_opmode_oneshot);
            close all force;          
        end
   
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            %inital visibility rules
            app.ActionLabel.Visible = false;
            app.Reconnect.Visible = "off";
            app.Directions.Visible = "off";
            app.EndSimulationButton.Visible = "off";
            app.ActionLabel.Visible = "off";
            app.mapInit = false;
            app.sim = remApi('remoteApi');
            app.sim.simxFinish(-1);
            app.clientID = app.sim.simxStart('127.0.0.1',19999,true,true,5000,5);             
            app.h = [0,0,0,0,0,0,0,0,0,0,0]; % 11 total joints
            app.open = true;
            if (app.clientID>-1)
                %could do something here. Not sure what i would do
                %success visibile rules
                r = app.sim.simxSynchronous(app.clientID, true);
                if r ~= 0
                  error('Failed to enable synchronous mode');
                end
                r = app.sim.simxStartSimulation(app.clientID, app.sim.simx_opmode_oneshot);
                app.sim.simxSynchronousTrigger(app.clientID);
                [r,app.h(1)] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_fl', app.sim.simx_opmode_blocking); % r is like a return code I think
                [r,app.h(2)] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_rl', app.sim.simx_opmode_blocking);
                [r,app.h(3)] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_rr', app.sim.simx_opmode_blocking); 
                [r,app.h(4)] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_fr', app.sim.simx_opmode_blocking);
                [r,app.youBotHandle] = app.sim.simxGetObjectHandle(app.clientID, 'youBot', app.sim.simx_opmode_blocking);
                app.ActionLabel.Visible = false;
                app.Reconnect.Visible = "off";
                app.Directions.Visible = "on";
                app.EndSimulationButton.Visible = "on";
                app.ActionLabel.Visible = "on";
                app.Running = true;
                
                %updateGlobalMap(app,app.vision,(app.globalTheta),(app.velocity),(app.angularVel),.2);
                app.TimerObj = timer('ExecutionMode', 'fixedSpacing', ...
                         'Period', 0.02, ... % Step every 0.2 sec
                         'TimerFcn', @(~,~) app.stepSimulation); 
                start(app.TimerObj);
                app.Running = true;
            else
                app.Reconnect.Visible = "on";                
                disp('Failed connecting to remote API server');
            end            
        end

        % Button pushed function: EndSimulationButton
        function EndSimulationButtonPushed(app, event)
            %end the simulation
            if app.clientID > -1
                stopSimulation(app);
                close all force;
            end

        end

        % Button pushed function: Reconnect
        function ReconnectButtonPushed(app, event)
            %attempt reconnect by calling startFcn            
            startupFcn(app);
            if app.clientID < 0
                app.ActionLabel.Visible = "on";
                app.ActionLabel.Text = "Reconnect Failed";
            else
                app.ActionLabel.Text = "";
            end
        end

        % Value changed function: UpdateIntervalsecondsEditField
        function UpdateIntervalsecondsEditFieldValueChanged(app, event)
            value = app.UpdateIntervalsecondsEditField.Value;          
        end

        % Button pushed function: StopSensorButton
        function StopSensorButtonPushed(app, event)
            stopSimulation(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if app.clientID > -1
                key = event.Key;
                value = "nil";
                switch key
                    case {'leftarrow',"A"}
                        app.inputAngularVelocity = -1;
                        value = "Rotating counter-clockwise";

                    case {'rightarrow', "D"}
                        app.inputAngularVelocity = 1;
                        value = "Rotating clockwise";

                    case {'uparrow', "W"}
                        app.inputVelocity = 1;
                        value = "Moving Forward";

                    case {'downarrow', "S"}
                        app.inputVelocity = -1;
                        value = "Moving Backward";

                    case {'space',"X"}
                        app.inputStop = true;
                        value = "Staying in place";
                    otherwise

                        % Code for other keys
                        disp(['Key pressed: ', key]);
                        value = strcat("Unknown Key: ",key);
                end
                app.ActionLabel.Text = value;
                app.ActionLabel.Visible = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Directions = uilabel(app.UIFigure);
            app.Directions.Text = {'Left Arrow - Rotate Left'; 'Right Arrow - Rotate Right'; 'Up Arrow - Forward'; 'Down Arrow - Backward'; 'Spacebar - Stop in place'};
            app.EndSimulationButton = uibutton(app.UIFigure, 'push');
            app.EndSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @EndSimulationButtonPushed, true);
            app.EndSimulationButton.Text = 'End Simulation';
            app.Reconnect = uibutton(app.UIFigure, 'push');
            app.Reconnect.ButtonPushedFcn = createCallbackFcn(app, @ReconnectButtonPushed, true);
            app.Reconnect.Visible = 'off';
            app.Reconnect.Text = 'Attempt Reconnect';
            app.ActionLabel = uilabel(app.UIFigure);
            app.ActionLabel.Text = '';
            app.UpdateIntervalsecondsEditFieldLabel = uilabel(app.UIFigure);
            app.UpdateIntervalsecondsEditFieldLabel.Text = 'Update Interval (seconds)';
            app.UpdateIntervalsecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateIntervalsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateIntervalsecondsEditFieldValueChanged, true);
            app.UpdateIntervalsecondsEditField.Value = 0.2;
            app.StopSensorButton = uibutton(app.UIFigure, 'push');
            app.StopSensorButton.ButtonPushedFcn = createCallbackFcn(app, @StopSensorButtonPushed, true);
            app.StopSensorButton.Text = 'Stop Sensor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = YouBotApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end